classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PARFileInterrogaterUIFigure     matlab.ui.Figure
        FileNameLabel                   matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        GetFileButton                   matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AgeDataLampLabel                matlab.ui.control.Label
        AgeDataLamp                     matlab.ui.control.Lamp
        IsotopicDataPanel               matlab.ui.container.Panel
        TIMSLampLabel                   matlab.ui.control.Label
        TIMSLamp                        matlab.ui.control.Lamp
        SIMSLampLabel                   matlab.ui.control.Label
        SIMSLamp                        matlab.ui.control.Lamp
        ElementalLampLabel              matlab.ui.control.Label
        ElementalLamp                   matlab.ui.control.Lamp
        ManModifiedLampLabel            matlab.ui.control.Label
        ManModifiedLamp                 matlab.ui.control.Lamp
        PlutoniumLampLabel              matlab.ui.control.Label
        PlutoniumLamp                   matlab.ui.control.Lamp
        AgeDatingPanel                  matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        SortByButtonGroup               matlab.ui.container.ButtonGroup
        AgeButton                       matlab.ui.control.RadioButton
        UncertaintyButton               matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        PlotUraniumDataButton           matlab.ui.control.Button
        UErrorBarsCheckBox              matlab.ui.control.CheckBox
        PlotPuDataButton                matlab.ui.control.Button
        SeriesPanel                     matlab.ui.container.Panel
        SeriesPresentListBoxLabel       matlab.ui.control.Label
        SeriesPresentListBox            matlab.ui.control.ListBox
        SeriesSelectedTextAreaLabel     matlab.ui.control.Label
        SeriesSelectedTextArea          matlab.ui.control.TextArea
        ClearSeriesButton               matlab.ui.control.Button
        PlotUforSelectedButton          matlab.ui.control.Button
        PlotAgeforSelectedButton        matlab.ui.control.Button
        PlotElementsforSelectedButton   matlab.ui.control.Button
        ElementsPresentinSelectionLabel  matlab.ui.control.Label
        ElementsPresentinSelectionTextArea  matlab.ui.control.TextArea
        ElementsPanel                   matlab.ui.container.Panel
        ElementsPresentinDatasetLabel   matlab.ui.control.Label
        ElementsPresentinDatasetListBox  matlab.ui.control.ListBox
        ElementsSelectedTextAreaLabel   matlab.ui.control.Label
        ElementsSelectedTextArea        matlab.ui.control.TextArea
        PlotMatrixButton                matlab.ui.control.Button
        ClearElementsButton             matlab.ui.control.Button
        ScatterButton                   matlab.ui.control.Button
        PlotAgeButton                   matlab.ui.control.Button
        ListParticlesButton             matlab.ui.control.Button
        ProjectandParticleListTextAreaLabel  matlab.ui.control.Label
        ProjectandParticleListTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        data; % Description
        elementsPresent;
        ElementsSelectedValues;
        SeriesSelectedValues;
    end
    
    methods (Access = private)

     
        function data = importParFile(app, excelFile)
    
            % Import the data
            data = readtable(excelFile);  
            
            % check to see if Project ID is present if so, just rename 
            % Project ID to AGENCY_NUMBER and everything will work
            
            if ismember("AGENCY_NUMBER", data.Properties.VariableNames)
                data.AGENCY_NUMBER = extractBefore(data.AGENCY_NUMBER,'-');
                data.AGENCY_NUMBER = categorical(data.AGENCY_NUMBER);
                app.SeriesPresentListBox.Items = unique(data.AGENCY_NUMBER);
            else
                app.SeriesPresentListBox.Items = unique(data.PROJECT_ID);
            end

            % Check for Plutonium
            app.PlutoniumLamp.Color = "red";
            app.PlotPuDataButton.Enable = false;
            if ismember("P9P", data.Properties.VariableNames)
                if nnz(~ismissing(data.P9P)) > 0
                    app.PlutoniumLamp.Color = "green";
                    app.PlotPuDataButton.Enable = true;
                end
            end
                
            % Check for presence of TIMS or SIMS data
            app.SIMSLamp.Color = "red";
            app.TIMSLamp.Color = "red";
            if ismember('TEC', data.Properties.VariableNames)
                if ismember("SIMS", unique(data.TEC))
                    app.SIMSLamp.Color = "green";
                end
                if ismember("TIMS", unique(data.TEC))
                    app.TIMSLamp.Color = "green";
                end
            end
            
            % Check for Man Modified Codes
            app.ManModifiedLamp.Color = "red";
            if ismember('MAN_MODIFIED', data.Properties.VariableNames)
                if ismember("Y", unique(data.MAN_MODIFIED))
                    app.ManModifiedLamp.Color = "green";
                end
            end
            
            % Check for the presence of age-dating data
            app.AgeDataLamp.Color = "red";
            app.PlotButton.Enable = false;
            if (ismember("UPPER", data.Properties.VariableNames) || ...
                    ismember("upper_95", data.Properties.VariableNames) || ...
                    ismember("x95_UPPER", data.Properties.VariableNames))
                if ismember("upper_95", data.Properties.VariableNames)
                    data = renamevars(data,["lower_95","upper_95"],["LOWER","UPPER"]);
                end
                if ismember("x95_UPPER", data.Properties.VariableNames)
                    data = renamevars(data,["x95_LOWER","x95_UPPER"],["LOWER","UPPER"]);
                end
                if sum(~ismissing(data.UPPER)) > 0
                    app.AgeDataLamp.Color = "green";
                    app.PlotButton.Enable = true;
                    app.AgeButton.Enable = true;
                    app.UncertaintyButton.Enable = true;
                end
            end
            
            % check for elemental data
            
            app.ElementalLamp.Color = 'red';
            app.elementsPresent = {};
            app.ElementsPresentinDatasetListBox.Items = app.elementsPresent;

%             if nnz(sum(ismember(data.Properties.VariableNames, 'EPROBE_STRING')))
            if(ismember('EPROBE_STRING', data.Properties.VariableNames) || ismember('Eprobe_String', data.Properties.VariableNames))
                
                if ismember('Eprobe_String', data.Properties.VariableNames)
                    data = renamevars(data,'Eprobe_String', 'EPROBE_STRING');
                end
                
                app.ElementalLamp.Color = 'green';

                % extract elemental data and append to data table
                % first check for duplicate column names
                
                if ismember('PU',data.Properties.VariableNames)
                    data = renamevars(data,"PU","PUYN");
                end
                if ismember('TH',data.Properties.VariableNames)
                    data = renamevars(data,"TH","THYN");
                end
                
                [data, app.elementsPresent] = parseElementalString(data);
                
                % next line is sub-optimal, needs to be cleaned up
                other = {'U5P', 'U4P', 'U6P'};
                app.elementsPresent = {app.elementsPresent{1,:}, other{1,:}};
                app.ElementsPresentinDatasetListBox.Items = app.elementsPresent;

            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetFileButton
        function GetFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.xlsx');
            fullpathname = strcat(pathname, filename);
%             fullpathname = "Data/MasterWorkBook.xlsx";
%             filename = "MasterWorkBook.xlsx";
            app.data = importParFile(app, fullpathname);
            app.FileNameEditField.Value = filename;
            app.UITable.ColumnName = app.data.Properties.VariableNames;
            app.UITable.Data = app.data;
            app.PlotUraniumDataButton.Enable = true;
            app.UErrorBarsCheckBox.Enable = true;
        end

        % Button pushed function: PlotUraniumDataButton
        function PlotUraniumDataButtonPushed(app, event)
            if app.UErrorBarsCheckBox.Value
                errorBarData(app.data);
            else
                plotUraniumData(app.data);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.AgeButton.Value
                plotAgeData(app.data, "Age", app.SeriesSelectedValues);
            elseif app.UncertaintyButton.Value
                plotAgeData(app.data,"Uncertainty", app.SeriesSelectedValues);
            end
        end

        % Button pushed function: PlotPuDataButton
        function PlotPuDataButtonPushed(app, event)
            plotPuData(app.data);
        end

        % Value changed function: ElementsPresentinDatasetListBox
        function ElementsPresentinDatasetListBoxValueChanged(app, event)
            value = app.ElementsPresentinDatasetListBox.Value;
            app.ElementsSelectedTextArea.Value = value;
            app.ElementsSelectedValues = value;
        end

        % Button pushed function: PlotMatrixButton
        function PlotMatrixButtonPushed(app, event)
            makePlotMatrix(app.data, app.ElementsSelectedValues);
        end

        % Value changed function: SeriesPresentListBox
        function SeriesPresentListBoxValueChanged(app, event)
            value = app.SeriesPresentListBox.Value;
            app.SeriesSelectedTextArea.Value = value;
            app.SeriesSelectedValues = value;
            app.ElementsPresentinSelectionTextArea.Value = ListElementsPresent(app.data, value);
        end

        % Button pushed function: ClearSeriesButton
        function ClearSeriesButtonPushed(app, event)
            app.SeriesSelectedValues = [];
            app.SeriesSelectedTextArea.Value = '';
        end

        % Button pushed function: PlotUforSelectedButton
        function PlotUforSelectedButtonPushed(app, event)
            gScatterUranium(app.data, app.SeriesSelectedValues)
        end

        % Button pushed function: ClearElementsButton
        function ClearElementsButtonPushed(app, event)
            app.ElementsSelectedValues = [];
            app.ElementsSelectedTextArea.Value = '';
        end

        % Button pushed function: ScatterButton
        function ScatterButtonPushed(app, event)
            newElementalScatter(app.data, app.ElementsSelectedValues)
        end

        % Button pushed function: PlotAgeButton
        function PlotAgeButtonPushed(app, event)
                plotAgeDataVariable(app.data, app.ElementsSelectedValues);
        end

        % Button pushed function: ListParticlesButton
        function ListParticlesButtonPushed(app, event)
            projectList = particleElementList(app.data, app.ElementsSelectedValues);
            app.ProjectandParticleListTextArea.Value = projectList;
        end

        % Button pushed function: PlotAgeforSelectedButton
        function PlotAgeforSelectedButtonPushed(app, event)
            plotAgeData(app.data, "Age", app.SeriesSelectedValues);
        end

        % Button pushed function: PlotElementsforSelectedButton
        function PlotElementsforSelectedButtonPushed(app, event)
            SeriesElementalScatter(app.data, app.ElementsSelectedValues, app.SeriesSelectedValues)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PARFileInterrogaterUIFigure = uifigure('Visible', 'off');
            app.PARFileInterrogaterUIFigure.Name = 'PAR File Interrogater';
            app.FileNameLabel = uilabel(app.PARFileInterrogaterUIFigure);
            app.FileNameLabel.Text = {'File Name'; ''};
            app.FileNameEditField = uieditfield(app.PARFileInterrogaterUIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.GetFileButton = uibutton(app.PARFileInterrogaterUIFigure, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.GetFileButton.Text = 'Get File';
            app.UITable = uitable(app.PARFileInterrogaterUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.AgeDataLampLabel = uilabel(app.PARFileInterrogaterUIFigure);
            app.AgeDataLampLabel.Text = {'Age Data'; ''};
            app.AgeDataLamp = uilamp(app.PARFileInterrogaterUIFigure);
            app.IsotopicDataPanel = uipanel(app.PARFileInterrogaterUIFigure);
            app.IsotopicDataPanel.Title = 'Isotopic Data';
            app.TIMSLampLabel = uilabel(app.IsotopicDataPanel);
            app.TIMSLampLabel.Text = 'TIMS';
            app.TIMSLamp = uilamp(app.IsotopicDataPanel);
            app.SIMSLampLabel = uilabel(app.IsotopicDataPanel);
            app.SIMSLampLabel.Text = {'SIMS'; ''};
            app.SIMSLamp = uilamp(app.IsotopicDataPanel);
            app.ElementalLampLabel = uilabel(app.PARFileInterrogaterUIFigure);
            app.ElementalLampLabel.Text = {'Elemental'; ''};
            app.ElementalLamp = uilamp(app.PARFileInterrogaterUIFigure);
            app.ManModifiedLampLabel = uilabel(app.PARFileInterrogaterUIFigure);
            app.ManModifiedLampLabel.Text = 'Man-Modified';
            app.ManModifiedLamp = uilamp(app.PARFileInterrogaterUIFigure);
            app.PlutoniumLampLabel = uilabel(app.PARFileInterrogaterUIFigure);
            app.PlutoniumLampLabel.Text = 'Plutonium';
            app.PlutoniumLamp = uilamp(app.PARFileInterrogaterUIFigure);
            app.AgeDatingPanel = uipanel(app.PARFileInterrogaterUIFigure);
            app.AgeDatingPanel.Title = 'Age Dating';
            app.PlotButton = uibutton(app.AgeDatingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.SortByButtonGroup = uibuttongroup(app.AgeDatingPanel);
            app.SortByButtonGroup.Title = 'Sort By';
            app.AgeButton = uiradiobutton(app.SortByButtonGroup);
            app.AgeButton.Enable = 'off';
            app.AgeButton.Text = 'Age';
            app.AgeButton.Value = true;
            app.UncertaintyButton = uiradiobutton(app.SortByButtonGroup);
            app.UncertaintyButton.Enable = 'off';
            app.UncertaintyButton.Text = 'Uncertainty';
            app.Panel = uipanel(app.PARFileInterrogaterUIFigure);
            app.Panel.Title = 'Panel';
            app.PlotUraniumDataButton = uibutton(app.Panel, 'push');
            app.PlotUraniumDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUraniumDataButtonPushed, true);
            app.PlotUraniumDataButton.Enable = 'off';
            app.PlotUraniumDataButton.Text = {'Plot Uranium Data'; ''};
            app.UErrorBarsCheckBox = uicheckbox(app.Panel);
            app.UErrorBarsCheckBox.Enable = 'off';
            app.UErrorBarsCheckBox.Text = {'U Error Bars'; ''};
            app.PlotPuDataButton = uibutton(app.Panel, 'push');
            app.PlotPuDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPuDataButtonPushed, true);
            app.PlotPuDataButton.Enable = 'off';
            app.PlotPuDataButton.Text = {'Plot Pu Data'; ''};
            app.SeriesPanel = uipanel(app.PARFileInterrogaterUIFigure);
            app.SeriesPanel.Title = 'Series';
            app.SeriesPresentListBoxLabel = uilabel(app.SeriesPanel);
            app.SeriesPresentListBoxLabel.Text = {'Series'; 'Present'};
            app.SeriesPresentListBox = uilistbox(app.SeriesPanel);
            app.SeriesPresentListBox.Items = {};
            app.SeriesPresentListBox.Multiselect = 'on';
            app.SeriesPresentListBox.ValueChangedFcn = createCallbackFcn(app, @SeriesPresentListBoxValueChanged, true);
            app.SeriesPresentListBox.Value = {};
            app.SeriesSelectedTextAreaLabel = uilabel(app.SeriesPanel);
            app.SeriesSelectedTextAreaLabel.Text = {'Series'; 'Selected'};
            app.SeriesSelectedTextArea = uitextarea(app.SeriesPanel);
            app.ClearSeriesButton = uibutton(app.SeriesPanel, 'push');
            app.ClearSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSeriesButtonPushed, true);
            app.ClearSeriesButton.Text = 'Clear Series';
            app.PlotUforSelectedButton = uibutton(app.SeriesPanel, 'push');
            app.PlotUforSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUforSelectedButtonPushed, true);
            app.PlotUforSelectedButton.Text = 'Plot U for Selected';
            app.PlotAgeforSelectedButton = uibutton(app.SeriesPanel, 'push');
            app.PlotAgeforSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAgeforSelectedButtonPushed, true);
            app.PlotAgeforSelectedButton.Text = 'Plot Age for Selected';
            app.PlotElementsforSelectedButton = uibutton(app.SeriesPanel, 'push');
            app.PlotElementsforSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotElementsforSelectedButtonPushed, true);
            app.PlotElementsforSelectedButton.Text = {'Plot Elements '; 'for Selected'};
            app.ElementsPresentinSelectionLabel = uilabel(app.SeriesPanel);
            app.ElementsPresentinSelectionLabel.Text = {'Elements '; 'Present'; 'in Selection'};
            app.ElementsPresentinSelectionTextArea = uitextarea(app.SeriesPanel);
            app.ElementsPanel = uipanel(app.PARFileInterrogaterUIFigure);
            app.ElementsPanel.Title = 'Elements';
            app.ElementsPresentinDatasetLabel = uilabel(app.ElementsPanel);
            app.ElementsPresentinDatasetLabel.Text = {'Elements'; 'Present'; 'in Dataset'; ''};
            app.ElementsPresentinDatasetListBox = uilistbox(app.ElementsPanel);
            app.ElementsPresentinDatasetListBox.Items = {};
            app.ElementsPresentinDatasetListBox.Multiselect = 'on';
            app.ElementsPresentinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @ElementsPresentinDatasetListBoxValueChanged, true);
            app.ElementsPresentinDatasetListBox.Value = {};
            app.ElementsSelectedTextAreaLabel = uilabel(app.ElementsPanel);
            app.ElementsSelectedTextAreaLabel.Text = {'Elements'; 'Selected'};
            app.ElementsSelectedTextArea = uitextarea(app.ElementsPanel);
            app.PlotMatrixButton = uibutton(app.ElementsPanel, 'push');
            app.PlotMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMatrixButtonPushed, true);
            app.PlotMatrixButton.Text = 'Plot Matrix';
            app.ClearElementsButton = uibutton(app.ElementsPanel, 'push');
            app.ClearElementsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearElementsButtonPushed, true);
            app.ClearElementsButton.Text = 'Clear Elements';
            app.ScatterButton = uibutton(app.ElementsPanel, 'push');
            app.ScatterButton.ButtonPushedFcn = createCallbackFcn(app, @ScatterButtonPushed, true);
            app.ScatterButton.Text = 'Scatter';
            app.PlotAgeButton = uibutton(app.ElementsPanel, 'push');
            app.PlotAgeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAgeButtonPushed, true);
            app.PlotAgeButton.Text = 'Plot Age';
            app.ListParticlesButton = uibutton(app.ElementsPanel, 'push');
            app.ListParticlesButton.ButtonPushedFcn = createCallbackFcn(app, @ListParticlesButtonPushed, true);
            app.ListParticlesButton.Text = 'List Particles';
            app.ProjectandParticleListTextAreaLabel = uilabel(app.PARFileInterrogaterUIFigure);
            app.ProjectandParticleListTextAreaLabel.Text = {'Project '; 'and'; 'Particle'; 'List'};
            app.ProjectandParticleListTextArea = uitextarea(app.PARFileInterrogaterUIFigure);
            % Show the figure after all components are created
            app.PARFileInterrogaterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PARFileInterrogaterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PARFileInterrogaterUIFigure)
        end
    end
end